classdef Dark_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MicroscopyinformationButtonGroup  matlab.ui.container.ButtonGroup
        PSFSpinnerLabel_6          matlab.ui.control.Label
        PSFSpinnerLabel_5          matlab.ui.control.Label
        NATextArea_2               matlab.ui.control.TextArea
        NATextArea_2Label          matlab.ui.control.Label
        PixelsizeTextArea_2        matlab.ui.control.TextArea
        PixelsizeTextArea_2Label   matlab.ui.control.Label
        PSFSpinnerLabel_4          matlab.ui.control.Label
        Button_2                   matlab.ui.control.Button
        WavelengthTextArea_2       matlab.ui.control.TextArea
        WavelengthTextArea_2Label  matlab.ui.control.Label
        DarkButton                 matlab.ui.control.StateButton
        PrameterButtonGroup        matlab.ui.container.ButtonGroup
        Switch                     matlab.ui.control.Switch
        PSFSpinnerLabel_3          matlab.ui.control.Label
        FactorTextArea             matlab.ui.control.TextArea
        FactorTextAreaLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Input
        Wave
        Pixel
        NA
        Background = 1
        Factor
        Nx
        Ny
        Nz
        image0
        filepath
    end

    methods (Access = private)

        function block_size = confirm_block(app,params,lp)
            PSF = PSF_Generator(app,params.emwavelength,params.pixelsize,params.NA,params.Nx,params.factor);
            PSF_Lo = abs(ifft2(fftshift(fft2(PSF)).*fftshift(lp)));
            PSF_Lo = PSF_Lo./max(max(PSF_Lo));
            % figure;plot(PSF(floor(params.Nx/2)+1,:))
            for count_x = floor(params.Nx/2):params.Nx
                if PSF_Lo(count_x,floor(params.Nx/2)) <0.01
                    break;
                end
            end
            block_size = count_x-floor(params.Nx/2);
        end

        function [ radiance ] = dehaze(app,image, omega, win_size, lambda )
            %DEHZE Summary of this function goes here
            %   Detailed explanation goes here

            if ~exist('omega', 'var')
                omega = 0.95;
            end

            if ~exist('win_size', 'var')
                win_size = 15;
            end

            if ~exist('lambda', 'var')
                lambda = 0.0001;
            end

            [m, n, ~] = size(image);

            dark_channel = get_dark_channel(app,image, win_size);

            atmosphere = get_atmosphere(app,image, dark_channel);

            trans_est = get_transmission_estimate(app,image, atmosphere, omega, win_size);

            L = get_laplacian(app,image);

            A = L + lambda * speye(size(L));
            b = lambda * trans_est(:);

            x = A \ b;

            transmission = reshape(x, m, n);

            radiance = get_radiance(app,image, transmission, atmosphere);

        end

        function [ radiance ] = dehaze_fast2( app, image, omega, win_size,EL,dep,thres )
            % Copyright (c) 2014 Stephen Tierney


            [Nx,Ny] = size(image);
            if ~exist('omega', 'var')
                omega = 0.95;
            end

            if ~exist('win_size', 'var')
                win_size = 15;
            end

            r = 15;
            res = 0.001;

            [m, n, ~] = size(image);

            Mask = zeros(Nx,Ny);
            Mask(image<thres) = 1;
            dark_channel = get_dark_channel(app,image.*Mask, win_size);
            min_atmosphere = get_atmosphere(app,image.*Mask, dark_channel);

            dark_channel = get_dark_channel(app,image, win_size);
            max_atmosphere = get_atmosphere(app,image, dark_channel);
            EL = EL - min(min(EL));
            rep_atmosphere_process = EL/max(max(EL))*(max_atmosphere-min_atmosphere)+min_atmosphere;
            rep_atmosphere_process = dep*rep_atmosphere_process;
            trans_est = get_transmission_estimate(app,rep_atmosphere_process,image, omega, win_size);
            x = guided_filter(app,image, trans_est, r, res);
            transmission = reshape(x, m, n);
            radiance = get_radiance(app,rep_atmosphere_process,image, transmission);


        end

        function atmosphere = get_atmosphere(app,image, dark_channel)
            % Copyright (c) 2014 Stephen Tierney

            [m, n, ~] = size(image);
            n_pixels = m * n;

            n_search_pixels = floor(n_pixels * 0.01);

            dark_vec = reshape(dark_channel, n_pixels, 1);

            image_vec = reshape(image, n_pixels,1);

            [~, indices] = sort(dark_vec, 'descend');

            accumulator = 0;

            for k = 1 : n_search_pixels
                accumulator = accumulator + image_vec(indices(k),:);
            end

            atmosphere = accumulator / n_search_pixels;

        end

        function dark_channel = get_dark_channel(app,image, win_size)

            [m, n, ~] = size(image);

            pad_size = floor(win_size/2);

            padded_image = padarray(image, [pad_size pad_size], Inf);

            dark_channel = zeros(m, n);

            for j = 1 : m
                for i = 1 : n
                    patch = padded_image(j : j + (win_size-1), i : i + (win_size-1), :);
                    dark_channel(j,i) = min(patch(:));
                end
            end


        end

        function trans_est = get_transmission_estimate(app,rep_atmosphere, image, omega, win_size)
            % Copyright (c) 2014 Stephen Tierney
            [m, n, ~] = size(image);

            %rep_atmosphere = repmat(atmosphere, m, n);

            trans_est = 1 - omega * get_dark_channel(app, image ./ rep_atmosphere, win_size);

        end


        function q = guided_filter(app,guide, target, radius, eps)
            % Copyright (c) 2014 Stephen Tierney

            [h, w] = size(guide);

            avg_denom = window_sum_filter(app,ones(h, w), radius);

            mean_g = window_sum_filter(app,guide, radius) ./ avg_denom;
            mean_t = window_sum_filter(app,target, radius) ./ avg_denom;

            corr_gg = window_sum_filter(app,guide .* guide, radius) ./ avg_denom;
            corr_gt = window_sum_filter(app,guide .* target, radius) ./ avg_denom;

            var_g = corr_gg - mean_g .* mean_g;
            cov_gt = corr_gt - mean_g .* mean_t;

            a = cov_gt ./ (var_g + eps);
            b = mean_t - a .* mean_g;

            mean_a = window_sum_filter(app,a, radius) ./ avg_denom;
            mean_b = window_sum_filter(app,b, radius) ./ avg_denom;

            q = mean_a .* guide + mean_b;

        end


        function q = guidedfilter(I, p, r, eps)
            %   GUIDEDFILTER   O(1) time implementation of guided filter.
            %
            %   - guidance image: I (should be a gray-scale/single channel image)
            %   - filtering input image: p (should be a gray-scale/single channel image)
            %   - local window radius: r
            %   - regularization parameter: eps

            [hei, wid] = size(I);
            N = boxfilter(ones(hei, wid), r); % the size of each local patch; N=(2r+1)^2 except for boundary pixels.

            mean_I = boxfilter(I, r) ./ N;
            mean_p = boxfilter(p, r) ./ N;
            mean_Ip = boxfilter(I.*p, r) ./ N;
            cov_Ip = mean_Ip - mean_I .* mean_p; % this is the covariance of (I, p) in each local patch.

            mean_II = boxfilter(I.*I, r) ./ N;
            var_I = mean_II - mean_I .* mean_I;

            a = cov_Ip ./ (var_I + eps); % Eqn. (5) in the paper;
            b = mean_p - a .* mean_I; % Eqn. (6) in the paper;

            mean_a = boxfilter(a, r) ./ N;
            mean_b = boxfilter(b, r) ./ N;

            q = mean_a .* I + mean_b; % Eqn. (8) in the paper;
        end


        function ImStack = imstackread(app,img)
            % This function can import 3D image stack.
            % The bit depth is limited to 8,16,32-bit
            % Written by Ethan Zhao, Jan. 2021
            % Tutorial: https://zhuanlan.zhihu.com/p/326688179/

            imgInfo = imfinfo(img);
            imgRow = imgInfo(1).Height;
            imgCol = imgInfo(1).Width;
            imgDepth = length(imgInfo);
            imgBitDepth = ['uint',num2str(imgInfo(1).BitDepth)];
            ImStack = zeros(imgRow, imgCol, imgDepth,imgBitDepth);
            for ii = 1 : length(imgInfo)
                ImStack(:,:,ii) = imread(img, ii);
            end

        end


        function [C, label, J] = kmeans(app,I, k)
            [m, n, p] = size(I);
            X = reshape(double(I), m*n, p);
            rng('default');
            C = X(randperm(m*n, k), :);
            J_prev = inf; iter = 0; J = []; tol = 1e-11;

            while true
                iter = iter + 1;
                dist = sum(X.^2, 2)*ones(1, k) + (sum(C.^2, 2)*ones(1, m*n))' - 2*X*C';
                [~,label] = min(dist, [], 2) ;  
                for i = 1:k
                    C(i, :) = mean(X(label == i , :)); 
                end
                J_cur = sum(sum((X - C(label, :)).^2, 2));
                J = [J, J_cur];

                if norm(J_cur-J_prev, 'fro') < tol
                    break;
                end
                if (iter==10)
                    break;
                end
                J_prev = J_cur;
            end
        end


        function [Lo] = Lp_denoise(app,image,params)

            Nx = params.Nx;
            Ny = params.Ny;
            NA = params.NA;
            emwavelength = params.emwavelength;
            pixel_size = params.pixelsize;

            res = 0.5 * emwavelength / NA/ params.factor;     % resolution
            sigmaLP = Ny / (res / pixel_size(1));    % objective cut-off frequency ???

            lp = lpgauss(Nx,Ny,sigmaLP);

            fft_image = fftshift(fft2(image));
            Lo = real(ifft2(ifftshift(fft_image.*fftshift(lp))));

        end


        function OTF = OTF_estimate(app,fft_image)
            [Nx,Ny] = size(fft_image);


            mx = floor(Ny/2)+1;
            my = floor(Ny/2)+1;

            r = zeros(Nx*Ny,1);
            k = zeros(Nx*Ny,1);
            for jx = 1:Nx
                for jy = 1:Ny
                    r((jx-1)*Nx+jy,1) = sqrt((jx-mx).^2 + (jy-my).^2);
                    k((jx-1)*Nx+jy,1) = fft_image(jx,jy);
                end
            end


        end


        function PSF = PSF_Generator(app,lambada,pixelsize,NA,w,factor)

            [X,Y]=meshgrid(linspace(0,w-1,w),linspace(0,w-1,w));
            scale=2*pi*NA/lambada*pixelsize;
            scale=scale*factor;

            R=sqrt(min(X,abs(X-w)).^2+min(Y,abs(Y-w)).^2);
            PSF=abs(2*besselj(1,scale*R+eps,1)./(scale*R+eps)).^2;
            PSF = PSF/(sum(sum(PSF)));
            PSF=fftshift(PSF);

        end


        function [Hi,Lo,lp,EL] = separateHiLo(app,image,params,deg)
        
            Nx = params.Nx;
            Ny = params.Ny;
            NA = params.NA;
            emwavelength = params.emwavelength;
            pixel_size = params.pixelsize;
   
            res = 0.5 * emwavelength / NA/ params.factor;     % resolution
            k_m = Ny / (res / pixel_size(1));    % objective cut-off frequency ???
            kc = nearest(k_m * 0.2);             % cut-off frequency between hp and lp filter
            sigmaLP = kc*2/2.355;                % Finding sigma value for low pass

            lp = lpgauss1(app,Nx,Ny,sigmaLP);
            hp = hpgauss1(app,Nx,Ny,sigmaLP);
            elp = lpgauss1(app,Nx,Ny,sigmaLP/deg);
            ehp = hpgauss1(app,Nx,Ny,sigmaLP/deg);

            Hi = real(ifft2(fft2(image).*hp));
            Lo = real(ifft2(fft2(image).*lp));

            fft_image = fftshift(fft2(image));
            Hi = real(ifft2(ifftshift(fft_image.*fftshift(hp))));
            Lo = real(ifft2(ifftshift(fft_image.*fftshift(lp))));
            EL = real(ifft2(fft2(image).*elp));
            EH = real(ifft2(fft2(image).*ehp));
        end

        function [ out ] = hpgauss1(app,H,W,SIGMA)
            %   Creates a 2D Gaussian filter for a Fourier space image of height H and
            %   width W. SIGMA is the standard deviation of the Gaussian.
            out=1-lpgauss(app,H,W,SIGMA);
        end

        function [ out ] = lpgauss1(app,H,W,SIGMA)
            %   Creates a 2D Gaussian filter for a Fourier space image
            %   W is the number of columns of the source image and H is the number of
            %   rows. SIGMA is the standard deviation of the Gaussian.
            H = double(H);
            W = double(W);
            kcx = (SIGMA);
            kcy = ((H/W)*SIGMA);
            temp0 = -floor(W/2);
            [x,y] = meshgrid(-floor(W/2):floor((W-1)/2), -floor(H/2):floor((H-1)/2));
            temp = -(x.^2/(kcx^2)+y.^2/(kcy^2));
            out = ifftshift(exp(temp));
            % out = ifftshift(exp(-(x.^2/(kcx^2)+y.^2/(kcy^2))));
        end


        function sum_img = window_sum_filter(app,image, r)

            % sum_img(x, y) = = sum(sum(image(x-r:x+r, y-r:y+r)));

            [h, w] = size(image);
            sum_img = zeros(size(image));

            % Y axis
            im_cum = cumsum(image, 1);

            sum_img(1:r+1, :) = im_cum(1+r:2*r+1, :);
            sum_img(r+2:h-r, :) = im_cum(2*r+2:h, :) - im_cum(1:h-2*r-1, :);
            sum_img(h-r+1:h, :) = repmat(im_cum(h, :), [r, 1]) - im_cum(h-2*r:h-r-1, :);

            % X axis
            im_cum = cumsum(sum_img, 2);

            sum_img(:, 1:r+1) = im_cum(:, 1+r:2*r+1);
            sum_img(:, r+2:w-r) = im_cum(:, 2*r+2:w) - im_cum(:, 1:w-2*r-1);
            sum_img(:, w-r+1:w) = repmat(im_cum(:, w), [1, r]) - im_cum(:, w-2*r:w-r-1);

        end


        function [ out ] = hpgauss(app,H,W,SIGMA)
            %   Creates a 2D Gaussian filter for a Fourier space image of height H and
            %   width W. SIGMA is the standard deviation of the Gaussian.
            out=1-lpgauss(app,H,W,SIGMA);
        end

        function [ out ] = lpgauss(app,H,W,SIGMA)
            %   Creates a 2D Gaussian filter for a Fourier space image
            %   W is the number of columns of the source image and H is the number of
            %   rows. SIGMA is the standard deviation of the Gaussian.
            H = double(H);
            W = double(W);
            kcx = (SIGMA);
            kcy = ((H/W)*SIGMA);
            temp0 = -floor(W/2);
            [x,y] = meshgrid(-floor(W/2):floor((W-1)/2), -floor(H/2):floor((H-1)/2));
            temp = -(x.^2/(kcx^2)+y.^2/(kcy^2));
            out = ifftshift(exp(temp));
            % out = ifftshift(exp(-(x.^2/(kcx^2)+y.^2/(kcy^2))));
        end


        function [ L ] = get_laplacian(app,image )
            %GET_LAPLACIAN

            [m, n, c] = size(image);

            img_size = m*n;

            win_rad = 1;

            epsilon = 0.0000001;

            max_num_neigh = (win_rad*2+1)^2;

            ind_mat = reshape( 1:img_size, m, n);

            indices = 1 : (m*n);

            num_ind = length(indices);

            max_num_vertex = max_num_neigh * num_ind;

            row_inds = zeros( max_num_vertex, 1 );
            col_inds = zeros( max_num_vertex, 1 );
            vals = zeros( max_num_vertex, 1 );

            len = 0;

            for k = 1 : length(indices);

                ind = indices(k);

                [i, j] = ind2sub( [m n], ind );

                m_min = max( 1, i - win_rad );
                m_max = min( m, i + win_rad );
                n_min = max( 1, j - win_rad );
                n_max = min( n, j + win_rad );

                win_inds = ind_mat( m_min : m_max, n_min : n_max );
                win_inds = win_inds(:);

                num_neigh = size( win_inds, 1 );

                win_image = image( m_min : m_max, n_min : n_max, : );
                win_image = reshape( win_image, num_neigh, c );

                win_mean = mean( win_image, 1 );

                win_var = inv( (win_image' * win_image / num_neigh) - (win_mean' * win_mean) + (epsilon / num_neigh * eye(c) ) );

                win_image = win_image - repmat( win_mean, num_neigh, 1 );

                win_vals = ( 1 + win_image * win_var * win_image' ) / num_neigh;

                sub_len = num_neigh*num_neigh;

                win_inds = repmat(win_inds, 1, num_neigh);

                row_inds(1+len: len+sub_len) = win_inds(:);

                win_inds = win_inds';

                col_inds(1+len: len+sub_len) = win_inds(:);

                vals(1+len: len+sub_len) = win_vals(:);

                len = len + sub_len;

            end

            A = sparse(row_inds(1:len),col_inds(1:len),vals(1:len),img_size,img_size);

            D = spdiags(sum(A,2),0,n*m,n*m);

            L = D - A;

        end

        function radiance = get_radiance(app,rep_atmosphere,image, transmission)
            % Copyright (c) 2014 Stephen Tierney

            [m, n, ~] = size(image);

            max_transmission = max(transmission, 0.1);

            radiance = ((image - rep_atmosphere) ./ max_transmission) + rep_atmosphere;

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function input_change(app, event)
            [filename,path] = uigetfile('*.tif','Save file name','ufilename');
            filepath = strcat(path,filename);
            image0 = double(imstackread(app,filepath));
            app.filepath = filepath;
            image0 = 255*(image0 - min(min(image0)))./(max(max(image0))-min(min(image0)));
            [Nx0,Ny0,~] = size(image0);
            [Nx,Ny,~] = size(image0);
            if Ny>Nx
                image0(Nx+1:Ny,:,:)=0;
            elseif Ny<Nx
                image0(:,Ny+1:Nx,:)=0;
            end
            [app.Nx,app.Ny,app.Nz] = size(image0);
            app.image0 = image0;
        end

        % Value changed function: FactorTextArea
        function factor_change(app, event)

        end

        % Value changed function: DarkButton
        function Process(app, event)
            %% Reconstruction parameters
            pad = 1;        %1-sysemtic,0-pad0
            denoise = 1;    % Guassion denoise
            thres = 70;     % Threshold to distinguish background and information

            %% Padding edge
            pad_size = 15;
            Nx=app.Nx;
            Ny=app.Ny;
            Nz=app.Nz;
            Nx0=Nx;
            Ny0=Ny;
            image0 = app.image0;
            result_stack = zeros(Nx,Ny,Nz);
            Lo_process_stack = zeros(Nx,Ny,Nz);
            Hi_stack = zeros(Nx,Ny,Nz);
            for jz = 1:Nz
                if pad ==1
                    image(:,:,jz) = padarray(image0(:,:,jz),[floor(Nx/pad_size)+1,floor(Ny/pad_size)+1],'symmetric');
                else
                    image(:,:,jz) = padarray(image0(:,:,jz),[floor(Nx/pad_size)+1,floor(Ny/pad_size)+1]);
                end
            end

            %% Basic parameters
            [params.Nx,params.Ny,~] = size(image);
            value = app.NATextArea_2.Value;
            params.NA = str2num(value{1});
            value = app.WavelengthTextArea_2.Value;
            params.emwavelength = str2num(value{1});
            value = app.PixelsizeTextArea_2.Value;
            params.pixelsize = str2num(value{1});
            value = app.FactorTextArea.Value;
            params.factor = str2num(value{1});

            %% Background setting
            if app.Background == 1
                maxtime = 2;
                deg_matrix = [6,3,1.2];   % 3-10
                dep_matrix = [3,3,2];   % 0.7-2
                hl_matrix = [1,1,1];    % 3-8
            elseif app.Background == 0
                maxtime=1;
                deg_matrix = [6];   % 3-10
                dep_matrix = [3];   % 0.7-2
                hl_matrix = [1];    % 3-8
            end

            %% Dark sectioning
            for time = 1:maxtime
                for jz = 1:Nz
                    deg = deg_matrix(time);   % 3-10
                    dep = dep_matrix(time);   % 0.7-2
                    hl = hl_matrix(time);    % 3-8
                    % Seperate spectrum and confirm block size
                    [Hi,Lo,lp,EL] = separateHiLo(app,squeeze(image(:,:,jz)),params,deg);
                    block_size = confirm_block(app,params,lp);
                    % Remove background for low-frequency part
                    Lo_process = dehaze_fast2(app,Lo, 0.95, block_size, EL,dep,thres);
                    result = Lo_process/hl + Hi;
                    % Cutting edge
                    Lo_process = Lo_process(floor(Nx/pad_size)+2:floor(Nx/pad_size)+Nx+1,floor(Ny/pad_size)+2:floor(Ny/pad_size)+Ny+1);
                    Lo = Lo(floor(Nx/pad_size)+2:floor(Nx/pad_size)+Nx+1,floor(Ny/pad_size)+2:floor(Ny/pad_size)+Ny+1);
                    Hi = Hi(floor(Nx/pad_size)+2:floor(Nx/pad_size)+Nx+1,floor(Ny/pad_size)+2:floor(Ny/pad_size)+Ny+1);
                    result = result(floor(Nx/pad_size)+2:floor(Nx/pad_size)+Nx+1,floor(Ny/pad_size)+2:floor(Ny/pad_size)+Ny+1);
                    % Saving results
                    result_stack(:,:,jz) = result;
                    Lo_process_stack(:,:,jz) = Lo_process;
                    Hi_stack(:,:,jz) = Hi;
                end
                image0 = result_stack;
                for jz = 1:Nz
                    if pad==1
                        image(:,:,jz) = padarray(image0(:,:,jz),[floor(Nx/pad_size)+1,floor(Ny/pad_size)+1],'symmetric');
                    else
                        image(:,:,jz) = padarray(image0(:,:,jz),[floor(Nx/pad_size)+1,floor(Ny/pad_size)+1]);
                    end
                end
            end

            %% Denoise
            result_final = zeros(Nx,Ny,Nz);
            for jz = 1:Nz
                if pad ==1
                    temp = padarray(result_stack(:,:,jz),[floor(Nx/pad_size)+1,floor(Ny/pad_size)+1],'symmetric');
                else
                    temp = padarray(result_stack(:,:,jz),[floor(Nx/pad_size)+1,floor(Ny/pad_size)+1]);
                end
                if denoise == 0
                    temp1 = temp;
                else
                    W = fspecial('gaussian',[3,3],1);
                    temp1 = imfilter(temp, W, 'replicate');
                end
                result_final(:,:,jz) = temp1(floor(Nx/pad_size)+2:floor(Nx/pad_size)+Nx+1,floor(Ny/pad_size)+2:floor(Ny/pad_size)+Ny+1);
            end

            %% Select regin of interst
            if Nx0~=Nx || Ny0~=Ny
                if Nx>Nx0
                    image0(Nx0+1:Nx,:,:)=[];
                end
                if Ny0>Ny
                    image0(:,Ny0+1:Ny,:)=[];
                end
            end

            %% Saving results
            maxnum = max(max(max(result_final)));
            final_image = uint16(65535*result_final./maxnum);
            filepath0 = app.filepath;
            filepath0(end-3:end)=[];
            stackfilename = strcat(filepath0,'_Dark.tif');
            for k = 1:Nz
                imwrite(final_image(:,:,k), stackfilename, 'WriteMode','append') % 写入stack图像
            end


        end

        % Value changed function: Switch
        function Is_severve(app, event)
            value = app.Switch.Value;
            if length(value) == 11
                app.Background = 0;
            else
                app.Background = 1;
            end
        end

        % Value changed function: NATextArea_2
        function NA_change(app, event)

        end

        % Value changed function: WavelengthTextArea_2
        function wave_change(app, event)

        end

        % Value changed function: PixelsizeTextArea_2
        function pixel_change(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PrameterButtonGroup = uibuttongroup(app.UIFigure);
            app.PrameterButtonGroup.Title = 'Prameter';
            app.FactorTextAreaLabel = uilabel(app.PrameterButtonGroup);
            app.FactorTextAreaLabel.Text = 'Factor';
            app.FactorTextArea = uitextarea(app.PrameterButtonGroup);
            app.FactorTextArea.ValueChangedFcn = createCallbackFcn(app, @factor_change, true);
            app.FactorTextArea.Value = {'2'};
            app.PSFSpinnerLabel_3 = uilabel(app.PrameterButtonGroup);
            app.PSFSpinnerLabel_3.Text = '0~2';
            app.Switch = uiswitch(app.PrameterButtonGroup, 'slider');
            app.Switch.Items = {'Is severe', 'Not severve'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @Is_severve, true);
            app.Switch.Value = 'Is severe';
            app.DarkButton = uibutton(app.UIFigure, 'state');
            app.DarkButton.ValueChangedFcn = createCallbackFcn(app, @Process, true);
            app.DarkButton.Text = 'Dark';
            app.MicroscopyinformationButtonGroup = uibuttongroup(app.UIFigure);
            app.MicroscopyinformationButtonGroup.Title = 'Microscopy information';
            app.WavelengthTextArea_2Label = uilabel(app.MicroscopyinformationButtonGroup);
            app.WavelengthTextArea_2Label.Text = 'Wavelength';
            app.WavelengthTextArea_2 = uitextarea(app.MicroscopyinformationButtonGroup);
            app.WavelengthTextArea_2.ValueChangedFcn = createCallbackFcn(app, @wave_change, true);
            app.WavelengthTextArea_2.Value = {'610'};
            app.Button_2 = uibutton(app.MicroscopyinformationButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @input_change, true);
            app.Button_2.Text = {'...'; ''};
            app.PSFSpinnerLabel_4 = uilabel(app.MicroscopyinformationButtonGroup);
            app.PSFSpinnerLabel_4.Text = 'Input';
            app.PixelsizeTextArea_2Label = uilabel(app.MicroscopyinformationButtonGroup);
            app.PixelsizeTextArea_2Label.Text = 'Pixelsize';
            app.PixelsizeTextArea_2 = uitextarea(app.MicroscopyinformationButtonGroup);
            app.PixelsizeTextArea_2.ValueChangedFcn = createCallbackFcn(app, @pixel_change, true);
            app.PixelsizeTextArea_2.Value = {'65'};
            app.NATextArea_2Label = uilabel(app.MicroscopyinformationButtonGroup);
            app.NATextArea_2Label.Text = 'NA';
            app.NATextArea_2 = uitextarea(app.MicroscopyinformationButtonGroup);
            app.NATextArea_2.ValueChangedFcn = createCallbackFcn(app, @NA_change, true);
            app.NATextArea_2.Value = {'1.49'};
            app.PSFSpinnerLabel_5 = uilabel(app.MicroscopyinformationButtonGroup);
            app.PSFSpinnerLabel_5.Text = 'nm';
            app.PSFSpinnerLabel_6 = uilabel(app.MicroscopyinformationButtonGroup);
            app.PSFSpinnerLabel_6.Text = 'nm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dark_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end